classdef app_TP1_25547_15526_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        PrincipalMenu               matlab.ui.container.Menu
        MainMenu                    matlab.ui.container.Menu
        HomeMenu                    matlab.ui.container.Menu
        HistoryMenu                 matlab.ui.container.Menu
        DevMenu                     matlab.ui.container.Menu
        RedMenu                     matlab.ui.container.Menu
        BlueMenu                    matlab.ui.container.Menu
        YellowMenu                  matlab.ui.container.Menu
        HomePanel                   matlab.ui.container.Panel
        Sinal_Verdadeiro            matlab.ui.control.DropDown
        Bt_Save                     matlab.ui.control.Button
        text7_2                     matlab.ui.control.Label
        Bt_Pause                    matlab.ui.control.Button
        Bt_Connect                  matlab.ui.control.Button
        Bt_AtualizaHSV              matlab.ui.control.Button
        V_yellow_max                matlab.ui.control.EditField
        V_blue_max                  matlab.ui.control.EditField
        V_red_max                   matlab.ui.control.EditField
        text16_3                    matlab.ui.control.Label
        text15_3                    matlab.ui.control.Label
        V_yellow_min                matlab.ui.control.EditField
        V_blue_min                  matlab.ui.control.EditField
        V_red_min                   matlab.ui.control.EditField
        S_yellow_max                matlab.ui.control.EditField
        S_blue_max                  matlab.ui.control.EditField
        S_red_max                   matlab.ui.control.EditField
        text16_2                    matlab.ui.control.Label
        text15_2                    matlab.ui.control.Label
        S_yellow_min                matlab.ui.control.EditField
        S_blue_min                  matlab.ui.control.EditField
        S_red_min                   matlab.ui.control.EditField
        H_yellow_max                matlab.ui.control.EditField
        H_blue_max                  matlab.ui.control.EditField
        H_red_max                   matlab.ui.control.EditField
        text16                      matlab.ui.control.Label
        text15                      matlab.ui.control.Label
        text14                      matlab.ui.control.Label
        Bt_calibrate_Yellow         matlab.ui.control.Button
        Bt_calibrate_Blue           matlab.ui.control.Button
        text13                      matlab.ui.control.Label
        Sinal_Detetado              matlab.ui.control.EditField
        text12                      matlab.ui.control.Label
        text11                      matlab.ui.control.Label
        text10                      matlab.ui.control.Label
        text9                       matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        text7                       matlab.ui.control.Label
        Bt_Errado                   matlab.ui.control.Button
        Bt_Correto                  matlab.ui.control.Button
        H_yellow_min                matlab.ui.control.EditField
        text6                       matlab.ui.control.Label
        H_blue_min                  matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        H_red_min                   matlab.ui.control.EditField
        text2                       matlab.ui.control.Label
        Bt_Stop_Processing          matlab.ui.control.Button
        Bt_Start_Processing         matlab.ui.control.Button
        Bt_calibrate_Red            matlab.ui.control.Button
        Im_Processed                matlab.ui.control.UIAxes
        Im_Original                 matlab.ui.control.UIAxes
        DevYellowPanel              matlab.ui.container.Panel
        Text_Proportion             matlab.ui.control.Label
        Text_RedPixelsProportion    matlab.ui.control.Label
        RedPixelsProportion         matlab.ui.control.EditField
        Text_BlackPixelsProportion  matlab.ui.control.Label
        BlackPixelsProportion       matlab.ui.control.EditField
        Text_TotalPixels            matlab.ui.control.Label
        Text_TotalYellowPixels      matlab.ui.control.Label
        TotalYellowPixels           matlab.ui.control.EditField
        Text_TotalRedPixels         matlab.ui.control.Label
        TotalRedPixels              matlab.ui.control.EditField
        Text_TotalBlackPixels       matlab.ui.control.Label
        TotalBlackPixels            matlab.ui.control.EditField
        Tit_RedInsideYellow         matlab.ui.control.Label
        Tit_BlackInsideYellow       matlab.ui.control.Label
        Tit_BiggestYellowObject     matlab.ui.control.Label
        Im_RedInsideYellow          matlab.ui.control.UIAxes
        Im_BlackInsideYellow        matlab.ui.control.UIAxes
        Im_BiggestYellowObject      matlab.ui.control.UIAxes
        HistoryPanel                matlab.ui.container.Panel
        Text_MetricsGraphic         matlab.ui.control.Label
        Text_SamplesGraphic         matlab.ui.control.Label
        Text_TotalSamples           matlab.ui.control.Label
        Box_TotalSamples            matlab.ui.control.EditField
        UITable                     matlab.ui.control.Table
        Text_Recall                 matlab.ui.control.Label
        Box_Recall                  matlab.ui.control.EditField
        Text_Precision              matlab.ui.control.Label
        Box_Precision               matlab.ui.control.EditField
        Text_Accertivity            matlab.ui.control.Label
        Box_Accertivity             matlab.ui.control.EditField
        MetricsGraphic              matlab.ui.control.UIAxes
        Grafico                     matlab.ui.control.UIAxes
        DevBluePanel                matlab.ui.container.Panel
        Text_Circular               matlab.ui.control.Label
        Text_Circularity            matlab.ui.control.Label
        Text_WhiteObjects           matlab.ui.control.Label
        Text_WhiteArrowCenter       matlab.ui.control.Label
        Text_BlueSignalCenter       matlab.ui.control.Label
        Text_Square                 matlab.ui.control.Label
        Text_SquareProportion       matlab.ui.control.Label
        Circularity                 matlab.ui.control.EditField
        WhiteObjects                matlab.ui.control.EditField
        WhiteArrowCenter            matlab.ui.control.EditField
        BlueSignalCenter            matlab.ui.control.EditField
        SquareProportion            matlab.ui.control.EditField
        Tit_BiggestBlueObject       matlab.ui.control.Label
        Tit_WhiteInsideBlue         matlab.ui.control.Label
        Tit_RedInsideSquare         matlab.ui.control.Label
        Im_BiggestBlueObject        matlab.ui.control.UIAxes
        Im_WhiteInsideBlue          matlab.ui.control.UIAxes
        Im_RedInsideSquare          matlab.ui.control.UIAxes
        DevRedPanel                 matlab.ui.container.Panel
        Text_BlackProportion        matlab.ui.control.Label
        BlackProportion             matlab.ui.control.EditField
        TriangleRed                 matlab.ui.control.Label
        Text_BlackPixels            matlab.ui.control.Label
        BlackPixels                 matlab.ui.control.EditField
        Text_TrianglePixels         matlab.ui.control.Label
        TrianglePixels              matlab.ui.control.EditField
        CircleRed                   matlab.ui.control.Label
        Text_BlackObjects           matlab.ui.control.Label
        BlackObjects                matlab.ui.control.EditField
        Text_CircularityRed         matlab.ui.control.Label
        CircularityRed              matlab.ui.control.EditField
        Tit_BlackInsideTriangle     matlab.ui.control.Label
        Tit_BlackInsideCircle       matlab.ui.control.Label
        Tit_BiggestRedObject        matlab.ui.control.Label
        Im_BlackInsideTriangle      matlab.ui.control.UIAxes
        Im_BlackInsideCircle        matlab.ui.control.UIAxes
        Im_BiggestRedObject         matlab.ui.control.UIAxes
    end

    
%% Definição das propriedades publicas da classe (Variáveis Globais)
    properties (Access = public)
        vid;                        % imagem de video
        TimerData;                  % timer do processamento
        ProcessingActive;           % flag para ativar o processamento da imagem

        % RedHSV
        Hr_min;
        Hr_max;
        Sr_min;
        Sr_max;
        Vr_min;
        Vr_max;
        
        % BlueHSV
        Hb_min;
        Hb_max;
        Sb_min;
        Sb_max;
        Vb_min;
        Vb_max;

        % YellowHSV
        Hy_min;
        Hy_max;
        Sy_min;
        Sy_max;
        Vy_min;
        Vy_max;
    end

%% Funções determinantes de lógica a ser usadas na "main"
    methods (Access = private)     

%% Processamento - Lógica Principal para Distinção dos Sinais
        function Processamento(app, ~, ~)
            persistent H S V_eq IM V;                  % variáveis Estáticas

            trigger(app.vid);                          % dá um trigger
            IM=getdata(app.vid,1,'uint8');             % lê os dados da imagem
            imshow(IM, 'Parent', app.Im_Original);     % mostra a imagem RGB
            
            % Flag que permite o processamento da imagem           
            if app.ProcessingActive == true             % botão de Inicio de Processamento premido

                % Converte a imagem para HSV e equaliza o canal V (brilho)
                HSV = rgb2hsv(IM);
                H = HSV(:,:,1);
                S = HSV(:,:,2);
                V = HSV(:,:,3);
                V_eq = histeq(V);  % equaliza o brilho

                % Texto padrão para quando nenhum sinal é detetado
                app.Sinal_Detetado.Value = 'Nenhum';

                % Definir os thresholds de cada cor
                RedMask     = ((H < app.Hr_max | H > app.Hr_min) & S > app.Sr_min & V_eq > app.Vr_min);   
                BlueMask    = (H > app.Hb_min & H < app.Hb_max & S > app.Sb_min & V_eq > app.Vb_min);
                YellowMask  = (H > app.Hy_min & H < app.Hy_max & S > app.Sy_min & V_eq > app.Vy_min);
                BlackMask   = (H <= 1 & S >= 0 & V_eq <= 0.17);
                WhiteMask   = (H <= 1 & S <= 0.3 & V_eq >= 0.6);
                             
                % Soma todos os pixeis de uma cor da imagem
                redPixels = sum(RedMask(:));
                bluePixels = sum(BlueMask(:));
                yellowPixels = sum(YellowMask(:));

                % Detetar Sinais Vermelhos
                if redPixels > 30000
                    % Determina o maior objeto e atribui-o a uma mascara
                    redObj = bwlabel(RedMask);
                    propsRedObjs = regionprops(redObj,'Area');
                    [~, indexBigRedObj] = max([propsRedObjs.Area]);
                    maskBiggestRed = (redObj == indexBigRedObj);
                    maskBiggestRed = imfill(maskBiggestRed, "holes");

                    % Mostra Mascaras em HomePanel e DevRedPanel
                    imshow(maskBiggestRed, 'Parent', app.Im_Processed);
                    imshow(maskBiggestRed, 'Parent', app.Im_BiggestRedObject);

                    % Obter a circularidade do maior objeto
                    propsBiggestRed = regionprops(maskBiggestRed, 'Circularity'); 
                    app.CircularityRed.Value = num2str(propsBiggestRed(1).Circularity);

                        % Se for um sinal circular
                        if propsBiggestRed(1).Circularity > 0.7
                           circleErode = imerode(maskBiggestRed, strel('disk',5')); % remove linhas exteriores
                           circleClosed = imclose(circleErode, strel('disk',5')); % fecha buracos no sinal
                           circleInterior = imfill(circleClosed, "holes"); % preenche o interior do sinal

                           % Criação da Mascara "Preto Dentro do Circulo" 
                           blackInsideCircle = circleInterior & BlackMask;
                           blackInsideCircle = imclose(blackInsideCircle, strel('disk',8));
                           imshow(blackInsideCircle, 'Parent', app.Im_BlackInsideCircle); % mostra máscara

                           % Retira o numero de objetos na máscara
                           [~, blackObj] = bwlabel(blackInsideCircle);
                           app.BlackObjects.Value = num2str(blackObj); % mostra objetos

                           % Identifica o sinal
                           if blackObj >= 1
                                app.Sinal_Detetado.Value = 'Proibição de Ultrapassagem'; % se existir pelo menos um objeto preto dentro do sinal
                           elseif blackObj == 0
                                app.Sinal_Detetado.Value = 'Trânsito Proibido'; % se não tiver nenhum objeto preto dentro do sinal
                           end
                        
                        % Se for um sinal triangular
                        elseif propsBiggestRed(1).Circularity > 0.4 && propsBiggestRed(1).Circularity < 0.7 % Sinal Triangular
                            triangleInterior = imerode(maskBiggestRed, strel('disk',5));   % remove linhas exterior
                            triangleInterior = imfill(triangleInterior,'holes');           % preenche o interior do sinal
                                          
                            % Criação da Mascara "Preto Dentro do Triangulo" 
                            blackInsideTriangle = triangleInterior & BlackMask;
                            blackInsideTriangle = imopen(blackInsideTriangle, strel('disk', 3));   % remover pequenas regiões de 1º plano
                            imshow(blackInsideTriangle, 'Parent', app.Im_BlackInsideTriangle);     % mostra máscara

                            % Retira o numero de objetos na máscara
                            [~,blackObj] = bwlabel(blackInsideTriangle);  
                            app.BlackObjects.Value = num2str(blackObj);  % mostra objetos
                          
                            % Total de Pixeis do Triangulo e de Preto
                            totalTrianglePixels = sum(triangleInterior(:));
                            totalBlackPixels = sum(blackInsideTriangle(:));
                            app.TrianglePixels.Value = num2str(totalTrianglePixels);
                            app.BlackPixels.Value = num2str(totalBlackPixels);
                            
                            % Calcular proporção de pixeis pretos dentro do triangulo
                            blackPixelsProportion = totalBlackPixels/totalTrianglePixels;
                            app.BlackProportion.Value = num2str(blackPixelsProportion);
                    
                            % Identifica o sinal
                            if blackObj >= 1 && blackPixelsProportion > 0.11       % se houver pelo menos um objeto preto dentro do sinal e a sua proporção for > 0.11
                                app.Sinal_Detetado.Value = 'Perigo de Neve';
                            elseif blackObj == 1 && blackPixelsProportion > 0.05 && blackPixelsProportion < 0.11    % se houver pelo menos um objeto preto dentro do sinal e a sua proporção for > 0.11
                                app.Sinal_Detetado.Value = 'Perigo de Lomba';
                            else 
                                app.Sinal_Detetado.Value = 'Cedência de Passagem'; % se não houver objetos pretos dentro do sinal
                            end
                        end

                % Detetar Sinais Azuis
                elseif bluePixels > 30000
                    % Determina o maior objeto e atribuio a uma mascara
                    blueObj = bwlabel(BlueMask);                                % identificar objetos azuis conectados  
                    propsBlueObjs = regionprops(blueObj,'Area');
                    [~, indexBigBlueObj] = max([propsBlueObjs.Area]);           % identificar o maior objeto azul
                    maskBiggestBlue = (blueObj == indexBigBlueObj);
                    maskClosedBlue = imclose(maskBiggestBlue, strel('disk',5)); % preencher falhas
                    maskFilledBlue = imfill(maskClosedBlue,"holes");            % preencher o interior do objeto

                    % Mostra máscaras
                    imshow(maskBiggestBlue, 'Parent',app.Im_Processed);
                    imshow(maskBiggestBlue, 'Parent',app.Im_BiggestBlueObject);

                    % Propriedades do maior objeto para diferenciar sinais
                    propsBiggestBlue = regionprops(maskFilledBlue, 'Circularity','BoundingBox','Centroid');
                    app.Circularity.Value = num2str(propsBiggestBlue(1).Circularity);

                        % Verificar se é sinal circular
                        if propsBiggestBlue(1).Circularity > 0.85
            
                            % Criação da Mascara "Branco Dentro do Azul"
                            whiteInsideBlue = WhiteMask & maskFilledBlue;
                            whiteInsideBlue = imclose(whiteInsideBlue, strel('disk',12));
                            whiteInsideBlue = imfill(whiteInsideBlue, "holes");

                            % Identificar os objetos brancos
                            [~, whiteObj] = bwlabel(whiteInsideBlue);
                            app.WhiteObjects.Value = num2str(whiteObj);
                            imshow(whiteInsideBlue, 'Parent', app.Im_WhiteInsideBlue); % mostra mascara

                            % Verifica o número de objetos brancos dentro do sinal
                            if whiteObj >= 3
                                app.Sinal_Detetado.Value = 'Rotunda';   % se tiver pelo menos 3 objetos brancos

                            elseif whiteObj > 0 && whiteObj < 3         % se tiver entre 0 e 3 objetos brancos

                                % Retirar o centro do maior objeto branco
                                propsWhite = regionprops(whiteInsideBlue,'Centroid');
                                
                                % Determinar o centro do objeto branco e do sinal azul
                                whiteArrowCenter = propsWhite(1).Centroid(1);
                                blueSignalCenter = propsBiggestBlue(1).Centroid(1);
                                app.WhiteArrowCenter.Value = num2str(whiteArrowCenter);
                                app.BlueSignalCenter.Value = num2str(blueSignalCenter);

                                % Verifica se o centro da seta é maior que o centro do sinal
                                if whiteArrowCenter > blueSignalCenter
                                    app.Sinal_Detetado.Value = 'Obrigação à Direita';  % se o centro da seta for maior que o centro do sinal
                                else
                                    app.Sinal_Detetado.Value = 'Obrigação à Esquerda'; % se o centro da seta for menor que o centro do sinal
                                end
                            end

                        % Se não for circular
                        else
                            % Define a bounding box do sinal
                            width = propsBiggestBlue(1).BoundingBox(3);     % BoundingBox(3) = largura
                            height = propsBiggestBlue(1).BoundingBox(4);    % BoundingBox(4) = altura

                            % Calcular proporção da altura e largura do sinal
                            proportionSquare = width/height;
                            app.SquareProportion.Value = num2str(proportionSquare);

                            % Criação da Mascara "Vermelho Dentro do Quadrado"
                            redInsideSquare = maskFilledBlue & RedMask;
                            redInsideSquare = imclose(redInsideSquare, strel('disk',5)); % preencher falhas
                            imshow(redInsideSquare, 'Parent', app.Im_RedInsideSquare);   % mostra a máscara

                            % Se as proporções forem > 0.9 e < 1.1 e houver vermelho dentro quadrado
                            if proportionSquare > 0.9 && proportionSquare < 1.1 && any(redInsideSquare(:))
                                app.Sinal_Detetado.Value = 'Sem Saída';
                            end
                        end

                % Detetar Sinais Amarelos
                elseif yellowPixels > 20000
                    % Determina o maior objeto e atribui-o a uma mascara
                    BlackMask = imerode(BlackMask,strel('disk',5));             % erosão da máscara preta
                    YellowMask_Closed = imclose(YellowMask, strel('disk', 15));
                    yellowObj = bwlabel(YellowMask_Closed);                     % identifica objetos amarelos
                    propsYellowObjs = regionprops(yellowObj, 'Area');
                    [~, indexBigYellowObj] = max([propsYellowObjs.Area]);       % identifica o maior objeto amarelo
                    maskBiggestYellow = (yellowObj == indexBigYellowObj);


                    maskBiggestYellow = imdilate(maskBiggestYellow, strel('disk',10)); % aumenta a máscara amarela
                    maskBiggestYellow = imfill(maskBiggestYellow,'holes');             % preencher o interior do objeto

                    % Mostrar máscaras
                    imshow(maskBiggestYellow, 'Parent',app.Im_Processed);
                    imshow(maskBiggestYellow, 'Parent',app.Im_BiggestYellowObject);

                    % Criação das Mascaras "Preto Dentro do Amarelo" e "Vermelho Dentro do Amarelo"
                    blackInsideYellow = maskBiggestYellow & BlackMask;
                    blackFilled = imfill(blackInsideYellow,"holes");
                    redInsideBlack   = blackFilled & RedMask;

                    % Mostrar máscaras
                    imshow(blackInsideYellow, 'Parent', app.Im_BlackInsideYellow);
                    imshow(redInsideBlack, 'Parent', app.Im_RedInsideYellow);

                    % Soma todos os pixeis das máscaras criadas
                    yellowBlackPixels = sum(blackFilled(:));
                    blackRedPixels = sum(redInsideBlack(:));
                    yellowPixels = sum(maskBiggestYellow(:));
                    
                    % Mostra as somas anteriores no YellowDevPanel
                    app.TotalBlackPixels.Value = num2str(yellowBlackPixels);
                    app.TotalRedPixels.Value = num2str(blackRedPixels);
                    app.TotalYellowPixels.Value = num2str(yellowPixels);
            
                    % Proporção de Preto e Vermelho dentro do sinal amarelo
                    blackProportion = yellowBlackPixels / yellowPixels;
                    redProportion   = blackRedPixels / yellowBlackPixels;

                    % Amostragem das variáveis no DevPanel
                    app.BlackPixelsProportion.Value = num2str(blackProportion);
                    app.RedPixelsProportion.Value = num2str(redProportion);
            
                    % Identificar sinal amarelo
                    if blackProportion > 0.10 && redProportion < 0.01
                        app.Sinal_Detetado.Value = 'Aviso de Lomba';
                    elseif blackProportion > 0.10 && redProportion > 0.05
                        app.Sinal_Detetado.Value = 'Aviso Sinal de Semáforo';
                    end
                end
            end           
        end
        
%% Função para executar a calibração das cores
        function CalibrateColor(app, colorName, colorCode, H_min_prop, H_max_prop, S_min_prop, S_max_prop, V_min_prop, V_max_prop)

            stop(app.TimerData);          % para o vídeo original        
            app.ProcessingActive = false; % desativa a flag de processamento

            % Desativa botões 
            app.Bt_Start_Processing.Enable = 'off';
            app.Bt_Stop_Processing.Enable = 'off';
            app.Bt_calibrate_Red.Enable = 'off'; 
            app.Bt_calibrate_Blue.Enable = 'off'; 
            app.Bt_calibrate_Yellow.Enable = 'off';
            app.Bt_Errado.Enable = 'off';
            app.Bt_Correto.Enable = 'off';
            app.Bt_AtualizaHSV.Enable = 'off';

            % Fica com o último frame do video
            IM = getimage(app.Im_Original); 

            % Instrução para o utilizador calibrar a cor selecionada
            title(app.Im_Original, ['Selecione 5 pontos na cor ' upper(colorName)], 'Color', colorCode);
            
            % Retira as coordenadas dos 5 pontos escolhidos
            [x, y] = ginput(5);

            % Restaura o titulo
            title(app.Im_Original, 'Original', 'Color', 'k'); 

            % Conversão para HSV
            HSV = rgb2hsv(IM);
            % Separação de Canais
            H = HSV(:,:,1);
            S = HSV(:,:,2);
            V = HSV(:,:,3);
            % Equalização do brilho
            V_eq = histeq(V);

            % Arredondar coordenadas obtidas a inteiros
            x = round(x);
            y = round(y);
            
            % Extrair os valores HSV dos 5 pontos
            H_values = zeros(5, 1);
            S_values = zeros(5, 1);
            V_values = zeros(5, 1);
            for i = 1:5
                row = min(max(y(i), 1), size(IM, 1));
                col = min(max(x(i), 1), size(IM, 2));
                
                H_values(i) = H(row, col);
                S_values(i) = S(row, col);
                V_values(i) = V_eq(row, col);
            end
            
            % Calcular os min e max de cada canal
            app.(H_min_prop) = min(H_values) - 0.08;
            app.(H_max_prop) = max(H_values) + 0.08;
            app.(S_min_prop) = min(S_values) - 0.15;
            app.(S_max_prop) = 1.0; % S Max fixo em 1
            app.(V_min_prop) = min(V_values) - 0.15;
            app.(V_max_prop) = 1.0; % V Max fixo em 1
            
            % Assegurar que a margem calculada não assuma valores negativos
            if app.(H_min_prop) < 0
                app.(H_min_prop) = app.(H_min_prop) + 1.0;
            end
            if app.(H_max_prop) > 1
                app.(H_max_prop) = app.(H_max_prop) - 1.0;
            end
            if app.(V_min_prop) < 0
                app.(V_min_prop) = 0.0;
            end
            if app.(S_min_prop) < 0
                app.(S_min_prop) = 0.0;
            end

            % Preencher os campos do HSV min e max da interface
            app.(['H_' colorName '_min']).Value = num2str(app.(H_min_prop));
            app.(['H_' colorName '_max']).Value = num2str(app.(H_max_prop));
            app.(['S_' colorName '_min']).Value = num2str(app.(S_min_prop));
            app.(['S_' colorName '_max']).Value = num2str(app.(S_max_prop));
            app.(['V_' colorName '_min']).Value = num2str(app.(V_min_prop));
            app.(['V_' colorName '_max']).Value = num2str(app.(V_max_prop));
            
            % Ativa botões
            app.Bt_Start_Processing.Enable = 'on';
            app.Bt_calibrate_Red.Enable = 'on'; 
            app.Bt_calibrate_Blue.Enable = 'on'; 
            app.Bt_calibrate_Yellow.Enable = 'on';
            app.Bt_AtualizaHSV.Enable = 'on';
            
            % Torna a iniciar o timer
            start(app.TimerData);           
        end 

%% Função para trocar os paineis
        function switchPanel(app, targetPanel)

            % Array com todos os paineis existentes
            allPanels = [app.HomePanel, app.HistoryPanel, app.DevRedPanel, app.DevBluePanel, app.DevYellowPanel];

            % Desliga todos os paineis
            for i = 1:length(allPanels)
                allPanels(i).Visible = 'off';
            end

            % Liga apenas o painel pretendido
            targetPanel.Visible = 'on';
        end
    
%% Função para guardar os dados num ficheiro '.mat'
        function saveResult(app, verifica, sinal)
            
            % Criar uma tabela de uma linha para adicionar ao histórico
            NovaLinha = table(...
            string(app.Sinal_Detetado.Value), ...
            string(sinal), ...
            string(verifica), ...
            sum(app.Hr_min), sum(app.Hr_max), sum(app.Sr_min), sum(app.Sr_max), sum(app.Vr_min), sum(app.Vr_max), ...
            sum(app.Hb_min), sum(app.Hb_max), sum(app.Sb_min), sum(app.Sb_max), sum(app.Vb_min), sum(app.Vb_max), ...
            sum(app.Hy_min), sum(app.Hy_max), sum(app.Sy_min), sum(app.Sy_max), sum(app.Vy_min), sum(app.Vy_max), ...
            string(datetime('now', 'Format','dd-MMM-uuuu HH:mm:ss')), ...
            'VariableNames', ...
                {'Sinal_detetado', 'Sinal_Verdadeiro', 'Verification', ...
                'Hue_red_min', 'Hue_red_max', 'Saturarion_red_min', 'Saturarion_red_max', 'Value_red_min', 'Value_red_max', ...
                'Hue_blue_min', 'Hue_blue_max', 'Saturarion_blue_min', 'Saturarion_blue_max', 'Value_blue_min', 'Value_blue_max', ...
                'Hue_yellow_min', 'Hue_yellow_max', 'Saturarion_yellow_min', 'Saturarion_yellow_max', 'Value_yellow_min', 'Value_yellow_max', ...
                'Data_Hora'});
                
            % Se já existir o ficheiro
            if isfile('History.mat')
                DadosCarregados = load('History.mat', "Resultado"); % carrega os dados do ficheiro
                Resultado = [NovaLinha; DadosCarregados.Resultado]; % adiciona os novos dados aos existentes
            else
                Resultado = NovaLinha;
            end
            
            % Guarda dados
            save('History.mat', "Resultado");
            
            % Atualiza historico
            carregaHistorico(app);

            % Ativa botões e flag
            app.ProcessingActive = true;
            app.Bt_Pause.Enable = 'on';
            app.Bt_calibrate_Blue.Enable = 'on';
            app.Bt_calibrate_Red.Enable = 'on';
            app.Bt_calibrate_Yellow.Enable = 'on';
            app.Bt_AtualizaHSV.Enable = 'on';
            
            % Desativar Botões
            app.Bt_Correto.Enable = 'off';
            app.Bt_Errado.Enable = 'off';
        end

%% Função Historico
        function carregaHistorico(app)
            
            % Verificar se existe o ficheiro
            if isfile('History.mat')

                % Carrega os dados
                DadosCarregados = load('History.mat', "Resultado");
                
                % Atribuir os dados à tabela
                app.UITable.Data = DadosCarregados.Resultado;
                app.UITable.ColumnName = DadosCarregados.Resultado.Properties.VariableNames;

                % Converte a coluna para string e compara
                numCorretos = sum(string(DadosCarregados.Resultado.Verification) == "Correto"); % Conta corretos
                numErrados  = sum(string(DadosCarregados.Resultado.Verification) == "Errado");  % Conta errados
                total = numCorretos + numErrados;                                               % Total amostras
                app.Box_TotalSamples.Value = num2str(total);
                
                % Converte em percentagem
                percCorreto = (numCorretos/total) * 100;
                percErrado  = (numErrados/ total) * 100;
                
                % Define os eixos x/y
                categorias = categorical({'Corretos', 'Errados'});
                valores = [percCorreto, percErrado];
                
                % Cria o gráfico
                b = bar(app.Grafico, categorias, valores);
                
                % Limitar o eixo Y a 100%
                ylim(app.Grafico, [0 100]); 
                ylabel(app.Grafico, 'Percentagem (%)');
                title(app.Grafico, 'Taxa de Sucesso');
                
                % Estética do gráfico
                app.Grafico.YGrid = 'on'; % linhas de grelha
                b.FaceColor = 'flat';
                b.CData(1,:) = [0 0.5 0]; % verde para corretos
                b.CData(2,:) = [0.8 0 0]; % vermelha para errados

            % Se não existir ficheiro
            else
                app.UITable.Data = [];  % limpa a tabela
                cla(app.Grafico);       % limpa o gráfico
            end
        end        

%% Função para calcular e plotar métricas acumuladas
        function Metrics(app)

            % 1. Verificação se não existe o ficheiro do histórico
            if ~isfile("History.mat")
                cla(app.MetricsGraphic); % limpa o gráfico se não houver dados
                return;
            end
            
            % Carrega os dados do ficheiro
            Dados = load("History.mat", "Resultado");
            T = Dados.Resultado;
            TotalRegistos = height(T);
            
            % Vetores para guardar os resultados acumulados
            accumulatedAccuracy = zeros(TotalRegistos, 1);
            accumulatedPrecision = zeros(TotalRegistos, 1);
            accumulatedRecall = zeros(TotalRegistos, 1);
            
            % Contadores cumulativos
            accumulatedTP = 0;      % true positive     - o sistema identifica corretamente o sinal
            accumulatedFP = 0;      % false positive    - o sistema não idenfica corretamente o sinal
            accumulatedFN = 0;      % false negative    - o sistema falha a detetar um sinal
            accumulatedTN = 0;      % true negative     - o sistema acerta na falta da existencia de sinal

            %Calcular TP e FP
            for i = 1:TotalRegistos
                systemSignal = T.Sinal_detetado{i};     % sinal identificado pelo sistema
                realSignal = T.Sinal_Verdadeiro{i};     % sinal identificado pelo utilizador
                verificationResult = T.Verification{i}; % validação do utilizador
                
                % Verifica se o sinal detetado pelo sistema é diferente de nenhum
                Is_System_Positive = ~strcmp(systemSignal, 'Nenhum');
                
                % Se existir um sinal diferente de nenhum
                if Is_System_Positive 
                    if strcmp(verificationResult, 'Correto')    % verifica se o sinal está corretamente classificado
                        accumulatedTP = accumulatedTP + 1;      % é um true positive
                    else
                        accumulatedFP = accumulatedFP + 1;      % é um false positive
                    end
                
                % Se não existir identificação de sinal (sinal = nenhum)
                else 
                    % Verifica se o sinal real é diferente de nenhum
                    Is_Real_Positive = ~strcmp(realSignal, 'Nenhum');

                    % Se o sinal real for diferente de nenhum
                    if Is_Real_Positive 
                        accumulatedFN = accumulatedFN + 1;      % é um false negative
                    else 
                        accumulatedTN = accumulatedTN + 1;      % é um true negative (sinal real = nenhum)
                    end
                end
                
                % Calcular a taxa de acertividade
                accumulatedAccuracy(i) = ((accumulatedTP + accumulatedTN) / i) * 100; 

                % Calcular a taxa de precisão
                if (accumulatedTP + accumulatedFP) > 0
                    accumulatedPrecision(i) = (accumulatedTP / (accumulatedTP + accumulatedFP)) * 100;
                else
                    accumulatedPrecision(i) = 0;
                end
                
                % Calcular a taxa de recall
                if (accumulatedTP + accumulatedFN) > 0
                    accumulatedRecall(i) = (accumulatedTP / (accumulatedTP + accumulatedFN)) * 100;
                else
                    accumulatedRecall(i) = 0;
                end                        
            end

            % Construção do gráfico
            X = 1:TotalRegistos;     % eixo X = Total Registos
            cla(app.MetricsGraphic); % limpar o Gráfico para Plotar
            hold(app.MetricsGraphic, 'on');

            % Acertividade
            plot(app.MetricsGraphic, X, accumulatedAccuracy,'DisplayName', 'Accuracy', 'LineWidth', 2, 'Color', 'g');
            % Precisão
            plot(app.MetricsGraphic, X, accumulatedPrecision,'DisplayName', 'Precision', 'LineWidth', 2, 'Color', 'r');
            % Recall
            plot(app.MetricsGraphic, X, accumulatedRecall,'DisplayName', 'Recall', 'LineWidth', 2, 'Color', 'b');
            
            % Estrutura do gráfico
            app.MetricsGraphic.Title.String = 'Performance Metrics';
            app.MetricsGraphic.XLabel.String = 'Total of Samples';
            app.MetricsGraphic.YLabel.String = 'Percentage (%)';
            app.MetricsGraphic.YLim = [0 100];
            grid on;
            legend(app.MetricsGraphic, 'show', 'Location', 'Northeast');
            hold(app.MetricsGraphic, 'off');
            
            % Atribuir valores às caixas de texto de cada métrica
            app.Box_Accertivity.Value = num2str(accumulatedAccuracy(end), '%.2f%%');
            app.Box_Precision.Value = num2str(accumulatedPrecision(end), '%.2f%%');
            app.Box_Recall.Value = num2str(accumulatedRecall(end), '%.2f%%');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function app_OpeningFcn(app, varargin)
            switchPanel(app, app.HomePanel);    % garantir que a aplicação inicia no painel principal

            movegui(app.figure1, 'onscreen');   % garantir que a figura aparece no ecrã
            imaqreset;                          % limpeza da camera

            carregaHistorico(app);              % carrega dados do ficheiro .mat
            Metrics(app);                       % calcula as metricas com os dados que tem  no ficheiro .mat
        end

        % Button pushed function: Bt_Connect
        function Bt_ConnectPressed(app, event)
            app.Bt_Connect.Enable = 'off';      % desativa botao de conectar
            NumberFrameDisplayPerSecond = 20;   % define o frame rate
            
            app.vid = videoinput('winvideo', 1);        % liga a camara
            set(app.vid,'FramesPerTrigger',1);          % adquire apenas um frame
            set(app.vid,'TriggerRepeat',Inf);           % adquire em continuo
            set(app.vid,'ReturnedColorSpace','RGB');    % adquire imagem do tipo RGB
            triggerconfig(app.vid, 'Manual');
            
            % Cria um timer que chama a funcao Processamento
            app.TimerData = timer('TimerFcn', @(obj, event) Processamento(app, event),'Period', 1/NumberFrameDisplayPerSecond,'ExecutionMode','fixedRate','BusyMode','drop');            
            
            % Ativa restantes botoes
            app.Bt_calibrate_Red.Enable = 'on'; 
            app.Bt_calibrate_Blue.Enable = 'on'; 
            app.Bt_calibrate_Yellow.Enable = 'on';
            app.Bt_Errado.Enable = 'on';
            app.Bt_Correto.Enable = 'on';
            app.Bt_AtualizaHSV.Enable = 'on';

            % Desativa os botões
            app.Bt_Correto.Enable = 'off';
            app.Bt_Errado.Enable = 'off';

            start(app.vid);         % inicia o video 
            start(app.TimerData);   % inicia o timer
        end

        % Button pushed function: Bt_Start_Processing
        function Bt_Start_ProcessingButtonPushed(app, event)
            % Ativa botões e flag
            app.ProcessingActive = true;                % ativa flag para processar imagem
            app.Bt_Stop_Processing.Enable = 'on';       % habilita botão de parar o processamento
            app.Bt_Pause.Enable = 'on';
            
            % Desativa botão
            app.Bt_Start_Processing.Enable = 'off';     % desativa botao de processar
        end

        % Button pushed function: Bt_Stop_Processing
        function Bt_Stop_ProcessingButtonPushed(app, event)
            % Ativa botão
            app.Bt_Start_Processing.Enable = 'on';      

            % Desativa botões e flags
            app.ProcessingActive = false;               
            app.Bt_Stop_Processing.Enable = 'off';      
            app.Bt_Pause.Enable = 'off';
            app.Bt_Correto.Enable = 'off';
            app.Bt_Errado.Enable = 'off';

            % Garante que nao deteta nenhum sinal
            app.Sinal_Detetado.Value = 'Nenhum';
            
            % Limpa o plot da imagem processada
            cla(app.Im_Processed);                     
        end

        % Button pushed function: Bt_calibrate_Red
        function Bt_calibrate_RedButtonPushed(app, event)
            % Calibra a cor vermelha
            CalibrateColor(app, 'red', 'r', 'Hr_min', 'Hr_max', 'Sr_min', 'Sr_max', 'Vr_min', 'Vr_max');          
        end

        % Button pushed function: Bt_calibrate_Blue
        function Bt_calibrate_BlueButtonPushed(app, event)
            % Calibra a cor azul
            CalibrateColor(app, 'blue', 'b', 'Hb_min', 'Hb_max', 'Sb_min', 'Sb_max', 'Vb_min', 'Vb_max');  
        end

        % Button pushed function: Bt_calibrate_Yellow
        function Bt_calibrate_YellowButtonPushed(app, event)
            % Calibra a cor yellow
            CalibrateColor(app, 'yellow', 'y', 'Hy_min', 'Hy_max', 'Sy_min', 'Sy_max', 'Vy_min', 'Vy_max'); 
        end

        % Button pushed function: Bt_AtualizaHSV
        function Bt_AtualizaHSVButtonPushed(app, event)
            % Atualiza os valores de threshold inseridos manualmente
            
            % VERMELHO
            app.Hr_min = str2double(app.H_red_min.Value);
            app.Hr_max = str2double(app.H_red_max.Value);
            app.Sr_min = str2double(app.S_red_min.Value);
            app.Sr_max = str2double(app.S_red_max.Value);
            app.Vr_min = str2double(app.V_red_min.Value);
            app.Vr_max = str2double(app.V_red_max.Value);

            % AZUL
            app.Hb_min = str2double(app.H_blue_min.Value);
            app.Hb_max = str2double(app.H_blue_max.Value);
            app.Sb_min = str2double(app.S_blue_min.Value);
            app.Sb_max = str2double(app.S_blue_max.Value);
            app.Vb_min = str2double(app.V_blue_min.Value);
            app.Vb_max = str2double(app.V_blue_max.Value);

            % AMARELO
            app.Hy_min = str2double(app.H_yellow_min.Value);
            app.Hy_max = str2double(app.H_yellow_max.Value);
            app.Sy_min = str2double(app.S_yellow_min.Value);
            app.Sy_max = str2double(app.S_yellow_max.Value);
            app.Vy_min = str2double(app.V_yellow_min.Value);
            app.Vy_max = str2double(app.V_yellow_max.Value);
            
            app.Bt_Start_Processing.Enable = 'on';  % habilita o botão de processamento
            
            app.Bt_Stop_Processing.Enable = 'off';  % desativa o botão para parar processamento
            app.ProcessingActive = false;           % para o processamento
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            % Painel principal da aplicação
            switchPanel(app, app.HomePanel);  
        end

        % Menu selected function: HistoryMenu
        function HistoryMenuSelected(app, event)
            % Painel do histórico com as métricas
            switchPanel(app, app.HistoryPanel);
        end

        % Menu selected function: RedMenu
        function RedMenuSelected(app, event)
            % Painel com todas as informações do objeto identificado a vermelho
            switchPanel(app, app.DevRedPanel);
        end

        % Menu selected function: BlueMenu
        function BlueMenuSelected(app, event)
            % Painel com todas as informações do objeto identificado a azul
            switchPanel(app, app.DevBluePanel);
        end

        % Menu selected function: YellowMenu
        function YellowMenuSelected(app, event)
            % Painel com todas as informações do objeto identificado a amarelo
            switchPanel(app, app.DevYellowPanel);
        end

        % Button pushed function: Bt_Correto
        function Bt_CorretoButtonPushed(app, event)
            % Guarda no histórico o resultado da verificação manual da identificação do sinal
            saveResult(app, 'Correto', app.Sinal_Detetado.Value);

            % Atualizar o valor das métricas
            Metrics(app);
        end

        % Button pushed function: Bt_Errado
        function Bt_ErradoButtonPushed(app, event)
            % Instrução para o utilizador seguir após verificar um falso positivo
            app.Sinal_Verdadeiro.Value = 'Selecione o Sinal';

            % Torna visivel uma label e uma list box
            app.text7_2.Visible = 'on';        
            app.Sinal_Verdadeiro.Visible = 'on';
            
            % Torna visivel e ativa o botão de guardar a verificação
            app.Bt_Save.Visible = 'on';
            app.Bt_Save.Enable = 'on';
        end

        % Button pushed function: Bt_Pause
        function Bt_PauseButtonPushed(app, event)
            % Ativa botões
            app.Bt_Correto.Enable = 'on';
            app.Bt_Errado.Enable = 'on';
            
            % Desativa botões e flag
            app.ProcessingActive = false;
            app.Bt_Pause.Enable = 'off';
            app.Bt_calibrate_Blue.Enable = 'off';
            app.Bt_calibrate_Red.Enable = 'off';
            app.Bt_calibrate_Yellow.Enable = 'off';
            app.Bt_AtualizaHSV.Enable = 'off';
        end

        % Button pushed function: Bt_Save
        function Bt_SaveButtonPushed(app, event)
             % Guarda no histórico o resultado da verificação manual da identificação do sinal
            saveResult(app, 'Errado', app.Sinal_Verdadeiro.Value);

            % Atualizar o valor das métricas
            Metrics(app);
            
            % Oculta label, botão de guardar e list box
            app.text7_2.Visible = 'off';
            app.Sinal_Verdadeiro.Visible = 'off';
            app.Bt_Save.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'app';
            app.figure1.Tag = 'figure1';
            app.PrincipalMenu = uimenu(app.figure1);
            app.PrincipalMenu.Text = 'Menu';
            app.MainMenu = uimenu(app.PrincipalMenu);
            app.MainMenu.Text = 'Main';
            app.HomeMenu = uimenu(app.MainMenu);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.HistoryMenu = uimenu(app.MainMenu);
            app.HistoryMenu.MenuSelectedFcn = createCallbackFcn(app, @HistoryMenuSelected, true);
            app.HistoryMenu.Text = 'History';
            app.DevMenu = uimenu(app.PrincipalMenu);
            app.DevMenu.Text = 'Dev';
            app.RedMenu = uimenu(app.DevMenu);
            app.RedMenu.MenuSelectedFcn = createCallbackFcn(app, @RedMenuSelected, true);
            app.RedMenu.Text = 'Red';
            app.BlueMenu = uimenu(app.DevMenu);
            app.BlueMenu.MenuSelectedFcn = createCallbackFcn(app, @BlueMenuSelected, true);
            app.BlueMenu.Text = 'Blue';
            app.YellowMenu = uimenu(app.DevMenu);
            app.YellowMenu.MenuSelectedFcn = createCallbackFcn(app, @YellowMenuSelected, true);
            app.YellowMenu.Text = 'Yellow';
            app.DevRedPanel = uipanel(app.figure1);
            app.DevRedPanel.Title = 'DevRed';
            app.Im_BiggestRedObject = uiaxes(app.DevRedPanel);
            app.Im_BiggestRedObject.Tag = 'Im_Processed';
            app.Im_BlackInsideCircle = uiaxes(app.DevRedPanel);
            app.Im_BlackInsideCircle.Tag = 'Im_Processed';
            app.Im_BlackInsideTriangle = uiaxes(app.DevRedPanel);
            app.Im_BlackInsideTriangle.Tag = 'Im_Processed';
            app.Tit_BiggestRedObject = uilabel(app.DevRedPanel);
            app.Tit_BiggestRedObject.Tag = 'text8';
            app.Tit_BiggestRedObject.Text = 'Biggest Red Object';
            app.Tit_BlackInsideCircle = uilabel(app.DevRedPanel);
            app.Tit_BlackInsideCircle.Tag = 'text8';
            app.Tit_BlackInsideCircle.Text = 'Black Inside Circle';
            app.Tit_BlackInsideTriangle = uilabel(app.DevRedPanel);
            app.Tit_BlackInsideTriangle.Tag = 'text8';
            app.Tit_BlackInsideTriangle.Text = 'Black Inside Triangle';
            app.CircularityRed = uieditfield(app.DevRedPanel, 'text');
            app.CircularityRed.Tag = 'Sinal_Detetado';
            app.CircularityRed.Editable = 'off';
            app.Text_CircularityRed = uilabel(app.DevRedPanel);
            app.Text_CircularityRed.Tag = 'text10';
            app.Text_CircularityRed.Text = 'Circularity';
            app.BlackObjects = uieditfield(app.DevRedPanel, 'text');
            app.BlackObjects.Tag = 'Sinal_Detetado';
            app.BlackObjects.Editable = 'off';
            app.Text_BlackObjects = uilabel(app.DevRedPanel);
            app.Text_BlackObjects.Tag = 'text10';
            app.Text_BlackObjects.Text = 'Black Objects';
            app.CircleRed = uilabel(app.DevRedPanel);
            app.CircleRed.Tag = 'text8';
            app.CircleRed.Text = 'Circle';
            app.TrianglePixels = uieditfield(app.DevRedPanel, 'text');
            app.TrianglePixels.Tag = 'Sinal_Detetado';
            app.TrianglePixels.Editable = 'off';
            app.Text_TrianglePixels = uilabel(app.DevRedPanel);
            app.Text_TrianglePixels.Tag = 'text10';
            app.Text_TrianglePixels.Text = 'Triangle Pixels';
            app.BlackPixels = uieditfield(app.DevRedPanel, 'text');
            app.BlackPixels.Tag = 'Sinal_Detetado';
            app.BlackPixels.Editable = 'off';
            app.Text_BlackPixels = uilabel(app.DevRedPanel);
            app.Text_BlackPixels.Tag = 'text10';
            app.Text_BlackPixels.Text = 'Black Pixels';
            app.TriangleRed = uilabel(app.DevRedPanel);
            app.TriangleRed.Tag = 'text8';
            app.TriangleRed.Text = 'Triangle';
            app.BlackProportion = uieditfield(app.DevRedPanel, 'text');
            app.BlackProportion.Tag = 'Sinal_Detetado';
            app.BlackProportion.Editable = 'off';
            app.Text_BlackProportion = uilabel(app.DevRedPanel);
            app.Text_BlackProportion.Tag = 'text10';
            app.Text_BlackProportion.Text = 'Black Proportion';
            app.DevBluePanel = uipanel(app.figure1);
            app.DevBluePanel.Title = 'DevBlue';
            app.Im_RedInsideSquare = uiaxes(app.DevBluePanel);
            app.Im_RedInsideSquare.Tag = 'Im_Processed';
            app.Im_WhiteInsideBlue = uiaxes(app.DevBluePanel);
            app.Im_WhiteInsideBlue.Tag = 'Im_Processed';
            app.Im_BiggestBlueObject = uiaxes(app.DevBluePanel);
            app.Im_BiggestBlueObject.Tag = 'Im_Processed';
            app.Tit_RedInsideSquare = uilabel(app.DevBluePanel);
            app.Tit_RedInsideSquare.Tag = 'text8';
            app.Tit_RedInsideSquare.Text = 'Red Inside Square';
            app.Tit_WhiteInsideBlue = uilabel(app.DevBluePanel);
            app.Tit_WhiteInsideBlue.Tag = 'text8';
            app.Tit_WhiteInsideBlue.Text = 'White Inside Blue';
            app.Tit_BiggestBlueObject = uilabel(app.DevBluePanel);
            app.Tit_BiggestBlueObject.Tag = 'text8';
            app.Tit_BiggestBlueObject.Text = 'Biggest Blue Object';
            app.SquareProportion = uieditfield(app.DevBluePanel, 'text');
            app.SquareProportion.Tag = 'Sinal_Detetado';
            app.SquareProportion.Editable = 'off';
            app.BlueSignalCenter = uieditfield(app.DevBluePanel, 'text');
            app.BlueSignalCenter.Tag = 'Sinal_Detetado';
            app.BlueSignalCenter.Editable = 'off';
            app.WhiteArrowCenter = uieditfield(app.DevBluePanel, 'text');
            app.WhiteArrowCenter.Tag = 'Sinal_Detetado';
            app.WhiteArrowCenter.Editable = 'off';
            app.WhiteObjects = uieditfield(app.DevBluePanel, 'text');
            app.WhiteObjects.Tag = 'Sinal_Detetado';
            app.WhiteObjects.Editable = 'off';
            app.Circularity = uieditfield(app.DevBluePanel, 'text');
            app.Circularity.Tag = 'Sinal_Detetado';
            app.Circularity.Editable = 'off';
            app.Text_SquareProportion = uilabel(app.DevBluePanel);
            app.Text_SquareProportion.Tag = 'text10';
            app.Text_SquareProportion.Text = 'Square Proportion';
            app.Text_Square = uilabel(app.DevBluePanel);
            app.Text_Square.Tag = 'text8';
            app.Text_Square.Text = 'Square';
            app.Text_BlueSignalCenter = uilabel(app.DevBluePanel);
            app.Text_BlueSignalCenter.Tag = 'text10';
            app.Text_BlueSignalCenter.Text = 'Center of Blue Signal';
            app.Text_WhiteArrowCenter = uilabel(app.DevBluePanel);
            app.Text_WhiteArrowCenter.Tag = 'text10';
            app.Text_WhiteArrowCenter.Text = 'Center of White Arrow';
            app.Text_WhiteObjects = uilabel(app.DevBluePanel);
            app.Text_WhiteObjects.Tag = 'text10';
            app.Text_WhiteObjects.Text = 'White Objects On Circle';
            app.Text_Circularity = uilabel(app.DevBluePanel);
            app.Text_Circularity.Tag = 'text10';
            app.Text_Circularity.Text = 'Circularity';
            app.Text_Circular = uilabel(app.DevBluePanel);
            app.Text_Circular.Tag = 'text8';
            app.Text_Circular.Text = 'Circular';
            app.HistoryPanel = uipanel(app.figure1);
            app.HistoryPanel.Title = 'History';
            app.Grafico = uiaxes(app.HistoryPanel);
            app.Grafico.Tag = 'Grafico';
            app.MetricsGraphic = uiaxes(app.HistoryPanel);
            app.MetricsGraphic.Tag = 'Grafico';
            app.Box_Accertivity = uieditfield(app.HistoryPanel, 'text');
            app.Box_Accertivity.Tag = 'Sinal_Detetado';
            app.Box_Accertivity.Editable = 'off';
            app.Text_Accertivity = uilabel(app.HistoryPanel);
            app.Text_Accertivity.Tag = 'text10';
            app.Text_Accertivity.Text = 'Accertivity';
            app.Box_Precision = uieditfield(app.HistoryPanel, 'text');
            app.Box_Precision.Tag = 'Sinal_Detetado';
            app.Box_Precision.Editable = 'off';
            app.Text_Precision = uilabel(app.HistoryPanel);
            app.Text_Precision.Tag = 'text10';
            app.Text_Precision.Text = 'Precision';
            app.Box_Recall = uieditfield(app.HistoryPanel, 'text');
            app.Box_Recall.Tag = 'Sinal_Detetado';
            app.Box_Recall.Editable = 'off';
            app.Text_Recall = uilabel(app.HistoryPanel);
            app.Text_Recall.Tag = 'text10';
            app.Text_Recall.Text = 'Recall';
            app.UITable = uitable(app.HistoryPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Box_TotalSamples = uieditfield(app.HistoryPanel, 'text');
            app.Box_TotalSamples.Tag = 'Sinal_Detetado';
            app.Box_TotalSamples.Editable = 'off';
            app.Text_TotalSamples = uilabel(app.HistoryPanel);
            app.Text_TotalSamples.Tag = 'text10';
            app.Text_TotalSamples.Text = 'Total Samples';
            app.Text_SamplesGraphic = uilabel(app.HistoryPanel);
            app.Text_SamplesGraphic.Tag = 'text8';
            app.Text_SamplesGraphic.Text = 'Samples';
            app.Text_MetricsGraphic = uilabel(app.HistoryPanel);
            app.Text_MetricsGraphic.Tag = 'text8';
            app.Text_MetricsGraphic.Text = 'Metrics';
            app.DevYellowPanel = uipanel(app.figure1);
            app.DevYellowPanel.Title = 'DevYellow';
            app.Im_BiggestYellowObject = uiaxes(app.DevYellowPanel);
            app.Im_BiggestYellowObject.Tag = 'Im_Processed';
            app.Im_BlackInsideYellow = uiaxes(app.DevYellowPanel);
            app.Im_BlackInsideYellow.Tag = 'Im_Processed';
            app.Im_RedInsideYellow = uiaxes(app.DevYellowPanel);
            app.Im_RedInsideYellow.Tag = 'Im_Processed';
            app.Tit_BiggestYellowObject = uilabel(app.DevYellowPanel);
            app.Tit_BiggestYellowObject.Tag = 'text8';
            app.Tit_BiggestYellowObject.Text = 'Biggest Yellow Object';
            app.Tit_BlackInsideYellow = uilabel(app.DevYellowPanel);
            app.Tit_BlackInsideYellow.Tag = 'text8';
            app.Tit_BlackInsideYellow.Text = 'Black Inside Yellow';
            app.Tit_RedInsideYellow = uilabel(app.DevYellowPanel);
            app.Tit_RedInsideYellow.Tag = 'text8';
            app.Tit_RedInsideYellow.Text = 'Red Inside Yellow';
            app.TotalBlackPixels = uieditfield(app.DevYellowPanel, 'text');
            app.TotalBlackPixels.Tag = 'Sinal_Detetado';
            app.TotalBlackPixels.Editable = 'off';
            app.Text_TotalBlackPixels = uilabel(app.DevYellowPanel);
            app.Text_TotalBlackPixels.Tag = 'text10';
            app.Text_TotalBlackPixels.Text = 'Black Pixels Inside Yellow Signal';
            app.TotalRedPixels = uieditfield(app.DevYellowPanel, 'text');
            app.TotalRedPixels.Tag = 'Sinal_Detetado';
            app.TotalRedPixels.Editable = 'off';
            app.Text_TotalRedPixels = uilabel(app.DevYellowPanel);
            app.Text_TotalRedPixels.Tag = 'text10';
            app.Text_TotalRedPixels.Text = 'Red Pixels Inside Yellow Signal';
            app.TotalYellowPixels = uieditfield(app.DevYellowPanel, 'text');
            app.TotalYellowPixels.Tag = 'Sinal_Detetado';
            app.TotalYellowPixels.Editable = 'off';
            app.Text_TotalYellowPixels = uilabel(app.DevYellowPanel);
            app.Text_TotalYellowPixels.Tag = 'text10';
            app.Text_TotalYellowPixels.Text = 'Yellow Pixels Inside Yellow Signal';
            app.Text_TotalPixels = uilabel(app.DevYellowPanel);
            app.Text_TotalPixels.Tag = 'text8';
            app.Text_TotalPixels.Text = 'Total Pixels';
            app.BlackPixelsProportion = uieditfield(app.DevYellowPanel, 'text');
            app.BlackPixelsProportion.Tag = 'Sinal_Detetado';
            app.BlackPixelsProportion.Editable = 'off';
            app.Text_BlackPixelsProportion = uilabel(app.DevYellowPanel);
            app.Text_BlackPixelsProportion.Tag = 'text10';
            app.Text_BlackPixelsProportion.Text = 'Proportion of Black Pixels';
            app.RedPixelsProportion = uieditfield(app.DevYellowPanel, 'text');
            app.RedPixelsProportion.Tag = 'Sinal_Detetado';
            app.RedPixelsProportion.Editable = 'off';
            app.Text_RedPixelsProportion = uilabel(app.DevYellowPanel);
            app.Text_RedPixelsProportion.Tag = 'text10';
            app.Text_RedPixelsProportion.Text = 'Proportion of Red Pixels';
            app.Text_Proportion = uilabel(app.DevYellowPanel);
            app.Text_Proportion.Tag = 'text8';
            app.Text_Proportion.Text = 'Proportion';
            app.HomePanel = uipanel(app.figure1);
            app.HomePanel.Title = 'Panel';
            app.Im_Original = uiaxes(app.HomePanel);
            app.Im_Original.Tag = 'Im_Original';
            app.Im_Processed = uiaxes(app.HomePanel);
            app.Im_Processed.Tag = 'Im_Processed';
            app.Bt_calibrate_Red = uibutton(app.HomePanel, 'push');
            app.Bt_calibrate_Red.ButtonPushedFcn = createCallbackFcn(app, @Bt_calibrate_RedButtonPushed, true);
            app.Bt_calibrate_Red.Tag = 'Bt_calibrate_Red';
            app.Bt_calibrate_Red.Enable = 'off';
            app.Bt_calibrate_Red.Text = 'Calibrate Red';
            app.Bt_Start_Processing = uibutton(app.HomePanel, 'push');
            app.Bt_Start_Processing.ButtonPushedFcn = createCallbackFcn(app, @Bt_Start_ProcessingButtonPushed, true);
            app.Bt_Start_Processing.Tag = 'Bt_Start_Processing';
            app.Bt_Start_Processing.Enable = 'off';
            app.Bt_Start_Processing.Text = 'Start Processing';
            app.Bt_Stop_Processing = uibutton(app.HomePanel, 'push');
            app.Bt_Stop_Processing.ButtonPushedFcn = createCallbackFcn(app, @Bt_Stop_ProcessingButtonPushed, true);
            app.Bt_Stop_Processing.Tag = 'Bt_Stop_Processing';
            app.Bt_Stop_Processing.Enable = 'off';
            app.Bt_Stop_Processing.Text = 'Stop Processing';
            app.text2 = uilabel(app.HomePanel);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Red';
            app.H_red_min = uieditfield(app.HomePanel, 'text');
            app.H_red_min.Tag = 'H_red_min';
            app.text5 = uilabel(app.HomePanel);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Blue';
            app.H_blue_min = uieditfield(app.HomePanel, 'text');
            app.H_blue_min.Tag = 'H_blue_min';
            app.text6 = uilabel(app.HomePanel);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Yellow';
            app.H_yellow_min = uieditfield(app.HomePanel, 'text');
            app.H_yellow_min.Tag = 'H_yellow_min';
            app.Bt_Correto = uibutton(app.HomePanel, 'push');
            app.Bt_Correto.ButtonPushedFcn = createCallbackFcn(app, @Bt_CorretoButtonPushed, true);
            app.Bt_Correto.Tag = 'Bt_Correto';
            app.Bt_Correto.Enable = 'off';
            app.Bt_Correto.Text = 'Correct';
            app.Bt_Errado = uibutton(app.HomePanel, 'push');
            app.Bt_Errado.ButtonPushedFcn = createCallbackFcn(app, @Bt_ErradoButtonPushed, true);
            app.Bt_Errado.Tag = 'Bt_Errado';
            app.Bt_Errado.Enable = 'off';
            app.Bt_Errado.Text = 'Incorrect';
            app.text7 = uilabel(app.HomePanel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Signal Validation:';
            app.text8 = uilabel(app.HomePanel);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Original';
            app.text9 = uilabel(app.HomePanel);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Processed';
            app.text10 = uilabel(app.HomePanel);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Signal Detected:';
            app.text11 = uilabel(app.HomePanel);
            app.text11.Tag = 'text11';
            app.text11.Text = 'H';
            app.text12 = uilabel(app.HomePanel);
            app.text12.Tag = 'text12';
            app.text12.Text = 'V';
            app.Sinal_Detetado = uieditfield(app.HomePanel, 'text');
            app.Sinal_Detetado.Tag = 'Sinal_Detetado';
            app.Sinal_Detetado.Editable = 'off';
            app.text13 = uilabel(app.HomePanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'S';
            app.Bt_calibrate_Blue = uibutton(app.HomePanel, 'push');
            app.Bt_calibrate_Blue.ButtonPushedFcn = createCallbackFcn(app, @Bt_calibrate_BlueButtonPushed, true);
            app.Bt_calibrate_Blue.Tag = 'Bt_calibrate_Blue';
            app.Bt_calibrate_Blue.Enable = 'off';
            app.Bt_calibrate_Blue.Text = 'Calibrate Blue';
            app.Bt_calibrate_Yellow = uibutton(app.HomePanel, 'push');
            app.Bt_calibrate_Yellow.ButtonPushedFcn = createCallbackFcn(app, @Bt_calibrate_YellowButtonPushed, true);
            app.Bt_calibrate_Yellow.Tag = 'Bt_calibrate_Yellow';
            app.Bt_calibrate_Yellow.Enable = 'off';
            app.Bt_calibrate_Yellow.Text = 'Calibrate Yellow';
            app.text14 = uilabel(app.HomePanel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Parameters HSV';
            app.text15 = uilabel(app.HomePanel);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Min';
            app.text16 = uilabel(app.HomePanel);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Max';
            app.H_red_max = uieditfield(app.HomePanel, 'text');
            app.H_red_max.Tag = 'H_red_max';
            app.H_blue_max = uieditfield(app.HomePanel, 'text');
            app.H_blue_max.Tag = 'H_blue_max';
            app.H_yellow_max = uieditfield(app.HomePanel, 'text');
            app.H_yellow_max.Tag = 'H_yellow_max';
            app.S_red_min = uieditfield(app.HomePanel, 'text');
            app.S_red_min.Tag = 'S_red_min';
            app.S_blue_min = uieditfield(app.HomePanel, 'text');
            app.S_blue_min.Tag = 'S_blue_min';
            app.S_yellow_min = uieditfield(app.HomePanel, 'text');
            app.S_yellow_min.Tag = 'S_yellow_min';
            app.text15_2 = uilabel(app.HomePanel);
            app.text15_2.Tag = 'text15';
            app.text15_2.Text = 'Min';
            app.text16_2 = uilabel(app.HomePanel);
            app.text16_2.Tag = 'text16';
            app.text16_2.Text = 'Max';
            app.S_red_max = uieditfield(app.HomePanel, 'text');
            app.S_red_max.Tag = 'S_red_max';
            app.S_blue_max = uieditfield(app.HomePanel, 'text');
            app.S_blue_max.Tag = 'S_blue_max';
            app.S_yellow_max = uieditfield(app.HomePanel, 'text');
            app.S_yellow_max.Tag = 'S_yellow_max';
            app.V_red_min = uieditfield(app.HomePanel, 'text');
            app.V_red_min.Tag = 'V_red_min';
            app.V_blue_min = uieditfield(app.HomePanel, 'text');
            app.V_blue_min.Tag = 'V_blue_min';
            app.V_yellow_min = uieditfield(app.HomePanel, 'text');
            app.V_yellow_min.Tag = 'V_yellow_min';
            app.text15_3 = uilabel(app.HomePanel);
            app.text15_3.Tag = 'text15';
            app.text15_3.Text = 'Min';
            app.text16_3 = uilabel(app.HomePanel);
            app.text16_3.Tag = 'text16';
            app.text16_3.Text = 'Max';
            app.V_red_max = uieditfield(app.HomePanel, 'text');
            app.V_red_max.Tag = 'V_red_max';
            app.V_blue_max = uieditfield(app.HomePanel, 'text');
            app.V_blue_max.Tag = 'V_blue_max';
            app.V_yellow_max = uieditfield(app.HomePanel, 'text');
            app.V_yellow_max.Tag = 'V_yellow_max';
            app.Bt_AtualizaHSV = uibutton(app.HomePanel, 'push');
            app.Bt_AtualizaHSV.ButtonPushedFcn = createCallbackFcn(app, @Bt_AtualizaHSVButtonPushed, true);
            app.Bt_AtualizaHSV.Tag = 'Bt_AtualizaHSV';
            app.Bt_AtualizaHSV.Enable = 'off';
            app.Bt_AtualizaHSV.Text = 'Manually Input HSV Values';
            app.Bt_Connect = uibutton(app.HomePanel, 'push');
            app.Bt_Connect.ButtonPushedFcn = createCallbackFcn(app, @Bt_ConnectPressed, true);
            app.Bt_Connect.Text = 'Connect';
            app.Bt_Pause = uibutton(app.HomePanel, 'push');
            app.Bt_Pause.ButtonPushedFcn = createCallbackFcn(app, @Bt_PauseButtonPushed, true);
            app.Bt_Pause.Tag = 'Bt_Pause';
            app.Bt_Pause.Enable = 'off';
            app.Bt_Pause.Text = 'Pause';
            app.text7_2 = uilabel(app.HomePanel);
            app.text7_2.Tag = 'text7_2';
            app.text7_2.Visible = 'off';
            app.text7_2.Text = 'True Signal Displayed';
            app.Bt_Save = uibutton(app.HomePanel, 'push');
            app.Bt_Save.ButtonPushedFcn = createCallbackFcn(app, @Bt_SaveButtonPushed, true);
            app.Bt_Save.Tag = 'Bt_Save';
            app.Bt_Save.Enable = 'off';
            app.Bt_Save.Visible = 'off';
            app.Bt_Save.Text = 'Save';
            app.Sinal_Verdadeiro = uidropdown(app.HomePanel);
            app.Sinal_Verdadeiro.Items = {'Selecione o Sinal', 'Proibição de Ultrapassagem', 'Trânsito Proibido', 'Perigo de Neve', 'Perigo de Lomba', 'Cedência de Passagem', 'Rotunda', 'Obrigação à Direita', 'Obrigação à Esquerda', 'Sem Saída', 'Aviso de Lomba', 'Aviso Sinal de Semáforo'};
            app.Sinal_Verdadeiro.Editable = 'on';
            app.Sinal_Verdadeiro.Visible = 'off';
            app.Sinal_Verdadeiro.Value = 'Selecione o Sinal';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_TP1_25547_15526_FINAL(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)app_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end